classdef AlphaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        FileselectionButton        matlab.ui.control.Button
        WithoutprocessingCheckBox  matlab.ui.control.CheckBox
        QuitButton                 matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        SetcolorDropDownLabel      matlab.ui.control.Label
        SetcolorDropDown           matlab.ui.control.DropDown
        PlayButton                 matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image4                     matlab.ui.control.Image
        Image5                     matlab.ui.control.Image
        RealtimemodeCheckBox       matlab.ui.control.CheckBox
        X1Label_2                  matlab.ui.control.Label
        X2Label                    matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        SetshapeDropDownLabel      matlab.ui.control.Label
        SetshapeDropDown           matlab.ui.control.DropDown
        RecordCheckBox             matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        pretrained_detectorButton  matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
        meanscoreLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        IN_REAL_TIME_FUNC = false;
        NEED_QUIT = true;
        STOP = false;
        LOOP_RUNNING = true;
        check_quit = 0;
        vidObj;
        fileName = 0;
        filePath = "";
        flag = 0;
        inputSize = [224 224 3];
        centre_point = [113 113]; % 84 80
        pretrained_detector = 0;
        detector_file = "detector_color_label_resnet50_fix.mat";
        image_array_count = 0;
        image_array = zeros(10);
        end_score = 0;
    end
    
    methods (Access = private)
        
        function Play(app)
              v = VideoReader(app.fileName);
            while hasFrame(v)
                i = readFrame(v);
                app.Image5.ImageSource = i;
                pause(0.01);
            end
        end
        
        function results = RealTimePlay(app)
            app.pretrained_detector = load(app.detector_file); %load(app.detector_file);
            check_vertical = false;
            check_horizontal= false;
            if app.RecordCheckBox.Value == 1
                mkdir("Session\");
                video_folder = char("Session\" + string(char(randi([33 126], 1, 10))) + ".avi");
                video = VideoWriter(video_folder);
                open(video);
            end
            if app.RealtimemodeCheckBox.Value == 0
                v = VideoReader(app.fileName);
            end
            w = webcam('e2eSoft iVCam');
            while (true)
                app.IN_REAL_TIME_FUNC = true;
                if app.RealtimemodeCheckBox.Value == 0
                    if hasFrame(v)
                        inp = readFrame(v);
                    end
                else
                    inp=snapshot(w);
                end

                im=imresize(inp,[224 224]);
                %im = rot90(im);
                %[box, out] = ONE_BOXX3_mex(im);
                [box, out, f, box_count, max_score, mes] = ONE_BOXX3(im, app.pretrained_detector);
                app.end_score = app.end_score + max_score;
                app.image_array_count = app.image_array_count + 1;
                
                if app.SetshapeDropDown.Value == "Cross"
                    if ~isempty(box)
                        y1 = box(1) + (box(3)/2); 
                        y2 = box(2);
                        y3 = box(1) + (box(3)/2);
                        y4 = box(2) + box(4);
                        
                        x1 = box(1) + 20; 
                        x2 = box(2) + (box(4)/2);
                        x3 = box(1) + box(3) - 20;
                        x4 = box(2) + (box(4)/2);
                    end
                end
                
                if ~isempty(box)
                if app.SetshapeDropDown.Value == "Rectangle"
                    if f == 0
                        %out = insertObjectAnnotation(out,'rectangle',box(1:4),max_score(1), "Color", "red");
                        out = insertShape(out, 'Rectangle', box(1:4), "Color", app.SetcolorDropDown.Value);
                    else
                        %out = insertObjectAnnotation(out,'rectangle',box((box_count- 3):box_count), max_score, "Color", "red");
                        out = insertShape(out, 'Rectangle', box((box_count- 3):box_count), "Color", app.SetcolorDropDown.Value);
                    end
                    %out = insertShape(out, 'Rectangle', box, "Color", app.SetcolorDropDown.Value);
                end
                end
                if app.SetshapeDropDown.Value == "Cross"
                    out = insertShape(out,'Line',[y1 y2 y3 y4],'Color',app.SetcolorDropDown.Value);
                    out = insertShape(out,'Line',[x1 x2 x3 x4],'Color',app.SetcolorDropDown.Value);
                end
                
                out = insertShape(out, 'line', [113 125 113 101], "Color", "red");
                out = insertShape(out, 'line', [125 113 101 113], "Color", "red");
                if ~isempty(box)
%                     x1 = (box(1) + box(3))/2;
%                     x2 = (box(2) + box(4))/2;
                    x1 = box(1) + box(3)/2;
                    x2 = box(2) + box(4)/2;
                    txt1 = "X1: " + string(int32(x1));
                    txt2 = "X2: " + string(int32(x2));
                    app.X1Label_2.Text = char(txt1);
                    app.X2Label.Text = char(txt2);
                    drawnow;
                    if x1 > app.centre_point(1) + 15
                        app.Image3.ImageSource = 'green_arrow_right.png'; %Зелёная_стрелка2-removebg-preview.png
                        app.Image.ImageSource = 'grey_arrow_left_removebg-preview.png';
                        check_horizontal= false;
                    elseif x1 < app.centre_point(1) - 20
                        app.Image3.ImageSource = 'grey_arrow_right_removebg-preview.png';
                        app.Image.ImageSource = 'green_arrow_left.png';
                        check_horizontal= false;
                    else
                        out = insertShape(out, 'line', [113 125 113 101], "Color", "green");
                        check_horizontal= true;
                        app.Image3.ImageSource = 'grey_arrow_right_removebg-preview.png';
                        app.Image.ImageSource = 'grey_arrow_left_removebg-preview.png';
                    end
                    
                    if x2 < app.centre_point(2) - 20
                        app.Image2.ImageSource = 'green_arrow_up.png'; %Зелёная_стрелка-removebg-preview.png
                        app.Image4.ImageSource = 'grey_arrow_down_removebg-preview.png';
                        check_vertical = false;
                    elseif x2 > app.centre_point(2) + 15
                        app.Image4.ImageSource = 'green_arrow_down.png';
                        app.Image2.ImageSource = 'grey_arrow-removebg-preview___копия-removebg-preview.png';
                        check_vertical = false;
                    else
                        out = insertShape(out, 'line', [125 113 101 113], "Color", "green");
                        check_vertical = true;
                        app.Image2.ImageSource = 'grey_arrow-removebg-preview___копия-removebg-preview.png';
                        app.Image4.ImageSource = 'grey_arrow_down_removebg-preview.png';
                    end
                else
                    app.Image.ImageSource = 'grey_arrow_left_removebg-preview.png';
                    app.Image2.ImageSource = 'grey_arrow-removebg-preview___копия-removebg-preview.png';
                    app.Image3.ImageSource = 'grey_arrow_right_removebg-preview.png';
                    app.Image4.ImageSource = 'grey_arrow_down_removebg-preview.png';
                end
                
                if check_vertical && check_horizontal
                    app.Lamp.Color = "Blue";
                else
                    app.Lamp.Color = "Red";
                end
                
                if ~isempty(box) && box(3) > 80 && check_vertical && check_horizontal
                    out = insertShape(out, 'Rectangle', box, "Color", "blue");
                    app.Lamp.Color = "Green";
                else
                    out = insertShape(out, 'Rectangle', box, "Color", app.SetcolorDropDown.Value);
                end
                
                if app.LOOP_RUNNING == true && app.STOP == false
                    out = imresize(out,[480 720]); % 168 280
                    app.Image5.ImageSource = out;
                    
                    if app.RecordCheckBox.Value == 1
                        writeVideo(video, out);
                    end
                    
                    pause(0.01);
                else
                    app.Image.ImageSource = 'grey_arrow_left_removebg-preview.png';
                    app.Image2.ImageSource = 'grey_arrow-removebg-preview___копия-removebg-preview.png';
                    app.Image3.ImageSource = 'grey_arrow_right_removebg-preview.png';
                    app.Image4.ImageSource = 'grey_arrow_down_removebg-preview.png';
                    
                    if app.LOOP_RUNNING == false
                        results = 1;
                    elseif app.STOP == true
                        results = 2;
                    end
                    app.IN_REAL_TIME_FUNC = false;
                    
                    if app.RecordCheckBox.Value == 1
                        close(video);
                    end
                    
                    return;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {719, 719};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {135, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: FileselectionButton
        function FileselectionButtonPushed(app, event)
            [file, path] = uigetfile('*.*');
            app.fileName = file;
            app.filePath = path;
            if  app.fileName == 0
                return;
            else
                %app.vidObj = VideoReader(app.fileName);
                app.flag = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.NEED_QUIT = false;
            if app.RealtimemodeCheckBox.Value == 0
               if  app.fileName == 0
                   msgbox('Не выбран файл', "Error");
                   return;
               else
                   if (app.WithoutprocessingCheckBox.Value == 0)
                       str = erase(app.fileName, '.MOV');
                       txt = "Session" + str;
                       txt = char(txt);
                       mkdir(txt);
                       test_folder = char(txt + "\" + str);%char("C:\Users\MainUser\Documents\MATLAB\" + str);
                       mkdir(test_folder);
                       video_folder = char(txt + "\" + "result");%char("D:\result_video_snus\" + str + num2str(3) + ".avi");
                       mkdir(video_folder);
                       video_folder = string(video_folder);
                       video_folder = char(video_folder + str + ".avi");
                       video = VideoWriter(video_folder);
                       open(video);
                        
                       d = load('Trained_Cross_YOLO.mat', 'detector');
                       i = 1;
                       m_s = 0;
                       b_c = 0;
                    % mkdir("C:\Users\MainUser\Documents\MATLAB\HALF_METKA5_result_test_images");
                    % result_folder = "C:\Users\MainUser\Documents\MATLAB\HALF_METKA5_result_test_images";
                       while hasFrame(app.vidObj)
                           I = readFrame(app.vidObj);
                           I = imresize(I,[224 224]);
                           [bboxes,scores] = detect(d.detector, I);
                           if isempty(bboxes)
                              fname = fullfile(test_folder, ['result_test_image' num2str(i) '.jpg']);
                              imwrite(I, fname, 'jpg');
                           else
                               if length(scores) > 1
                                   for j = 1:length(scores)
                                       if j == 1
                                           m_s = scores(j);
                                           b_c = j * 4;
                                       else
                                           if scores(j) > m_s
                                               m_s = scores(j);
                                               b_c = j * 4;
                                           end
                                       end
                                   end
                                   app.flag = 1;
                               end
                               if app.flag == 0
                                   I = insertObjectAnnotation(I,'rectangle',bboxes(1:4),scores(1), "Color", app.SetcolorDropDown.Value);
                               else
                                   I = insertObjectAnnotation(I,'rectangle',bboxes((b_c - 3):b_c),m_s, "Color", app.SetcolorDropDown.Value);
                                   app.flag = 0;
                               end
                               fname = fullfile(test_folder, ['result_test_image' num2str(i) '.jpg']);
                               imwrite(I, fname, 'jpg');
                           end
                           i = i + 1;
                           writeVideo(video, I);
                       end
                      close(video);
                      app.fileName = video_folder;
                    end
                end
                results = RealTimePlay(app);
                if results == 1
                    closereq;
                elseif results == 2
                    app.Image5.ImageSource = 'images-removebg-preview.png';
                    app.NEED_QUIT = true;
                    app.STOP = false;
                end
           else
                results = RealTimePlay(app);
                if results == 1
                    closereq;
                elseif results == 2
                    app.Image5.ImageSource = 'images-removebg-preview.png';
                    app.NEED_QUIT = true;
                    app.STOP = false;
                end
           end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.LOOP_RUNNING = false;        
            if app.NEED_QUIT == true
                closereq;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.IN_REAL_TIME_FUNC
                app.STOP = true;
                app.end_score = app.end_score / app.image_array_count;
                app.meanscoreLabel.Text = char("mean score: " + app.end_score);
            end
        end

        % Button pushed function: pretrained_detectorButton
        function pretrained_detectorButtonPushed(app, event)
            [file, path] = uigetfile('*.*');
            app.detector_file = file;
            app.Label_2.Text = char(app.detector_file);
            if  app.fileName == 0
                return;
            else
                %app.vidObj = VideoReader(app.fileName);
                %app.flag = true;
                app.Label_2.Text = char(app.detector_file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileselectionButton = uibutton(app.LeftPanel, 'push');
            app.FileselectionButton.ButtonPushedFcn = createCallbackFcn(app, @FileselectionButtonPushed, true);
            app.FileselectionButton.Text = 'File selection';
            app.WithoutprocessingCheckBox = uicheckbox(app.LeftPanel);
            app.WithoutprocessingCheckBox.Text = 'Without processing';
            app.QuitButton = uibutton(app.LeftPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.RightPanel = uipanel(app.GridLayout);
            app.SetcolorDropDownLabel = uilabel(app.RightPanel);
            app.SetcolorDropDownLabel.Text = 'Set color';
            app.SetcolorDropDown = uidropdown(app.RightPanel);
            app.SetcolorDropDown.Items = {'Green', 'Red', 'Yellow', 'Magenta'};
            app.SetcolorDropDown.Value = 'Green';
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'grey_arrow_left_removebg-preview.png';
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = 'grey_arrow-removebg-preview___копия-removebg-preview.png';
            app.Image3 = uiimage(app.RightPanel);
            app.Image3.ImageSource = 'grey_arrow_right_removebg-preview.png';
            app.Image4 = uiimage(app.RightPanel);
            app.Image4.ImageSource = 'grey_arrow_down_removebg-preview.png';
            app.Image5 = uiimage(app.RightPanel);
            app.Image5.ImageSource = 'images-removebg-preview.png';
            app.RealtimemodeCheckBox = uicheckbox(app.RightPanel);
            app.RealtimemodeCheckBox.Text = 'Real time mode';
            app.RealtimemodeCheckBox.Value = true;
            app.X1Label_2 = uilabel(app.RightPanel);
            app.X1Label_2.Text = 'Координаты X1';
            app.X2Label = uilabel(app.RightPanel);
            app.X2Label.Text = 'Координаты X2';
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetshapeDropDownLabel = uilabel(app.RightPanel);
            app.SetshapeDropDownLabel.Text = 'Set shape';
            app.SetshapeDropDown = uidropdown(app.RightPanel);
            app.SetshapeDropDown.Items = {'Rectangle', 'Cross'};
            app.SetshapeDropDown.Value = 'Rectangle';
            app.RecordCheckBox = uicheckbox(app.RightPanel);
            app.RecordCheckBox.Text = 'Record';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'Посадка';
            app.Lamp = uilamp(app.RightPanel);
            app.pretrained_detectorButton = uibutton(app.RightPanel, 'push');
            app.pretrained_detectorButton.ButtonPushedFcn = createCallbackFcn(app, @pretrained_detectorButtonPushed, true);
            app.pretrained_detectorButton.Text = 'pretrained_detector';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = 'Название детектора';
            app.meanscoreLabel = uilabel(app.RightPanel);
            app.meanscoreLabel.Text = 'mean score:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AlphaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end